classdef lineampprogram < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LoadFolderButton               matlab.ui.control.Button
        LoadFolderforButtonGroup       matlab.ui.container.ButtonGroup
        CircuitAButton                 matlab.ui.control.RadioButton
        CircuitBButton                 matlab.ui.control.RadioButton
        CircuitCButton                 matlab.ui.control.RadioButton
        CircuitDButton                 matlab.ui.control.RadioButton
        CircuitEButton                 matlab.ui.control.RadioButton
        CircuitFButton                 matlab.ui.control.RadioButton
        LampA                          matlab.ui.control.Lamp
        LampB                          matlab.ui.control.Lamp
        LampC                          matlab.ui.control.Lamp
        LampD                          matlab.ui.control.Lamp
        LampE                          matlab.ui.control.Lamp
        LampF                          matlab.ui.control.Lamp
        OutputTextAreaLabel            matlab.ui.control.Label
        OutputTextArea                 matlab.ui.control.TextArea
        TrainModelButton               matlab.ui.control.Button
        TrainModelLabel                matlab.ui.control.Label
        SimilarityIndexTextAreaLabel   matlab.ui.control.Label
        SimilarityIndexTextArea        matlab.ui.control.TextArea
        HighesteScoreTextAreaLabel     matlab.ui.control.Label
        HighesteScoreTextArea          matlab.ui.control.TextArea
        TrainModelwithExtendedFeaturesButton_2  matlab.ui.control.Button
        PlotConfusionMatrixButton      matlab.ui.control.Button
        PlotTHDAButton                 matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        ExtractFeaturesButton          matlab.ui.control.Button
        ExtractExtendedFeaturesButton  matlab.ui.control.Button
        TrainModelLabel_2              matlab.ui.control.Label
        PlotTHDBButton                 matlab.ui.control.Button
        PlotTHDCButton                 matlab.ui.control.Button
        PlotTHDDButton                 matlab.ui.control.Button
        PlotTHDEButton                 matlab.ui.control.Button
        PlotTHDFButton                 matlab.ui.control.Button
        HoldSwitchLabel                matlab.ui.control.Label
        HoldSwitch                     matlab.ui.control.Switch
        PlotConfusionMatrixforModelExtButton  matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
      switchFlag = [0,0,0,0,0,0];
      selpath; 
      
      dirCircuitA;
      dirCircuitB;
      dirCircuitC;
      dirCircuitD;
      dirCircuitE;
      dirCircuitF;
      
      datasetA = [];
      datasetB = [];
      datasetC = [];
      datasetD = [];
      datasetE = [];
      datasetF = [];
     
      datasetAenhanced = [];
      datasetBenhanced = [];
      datasetCenhanced = [];
      datasetDenhanced = [];
      datasetEenhanced = [];
      datasetFenhanced = []; % Datasets for storage
      
      trainingData = [];
      trainingDataEnhanced = [];
      
      X = [];
      X_Enhanced = [];
      Y = [];
      Y_Enhanced = [];
      
      oofLabel = [];
      
      THDA;
      THDB;
      THDC;
      THDD;
      THDE;
      THDF;
      
    end
    
    methods (Access = private)
        
        function [analysisVector] = fullAnalysis(app,file)

            %import fullfile path
            [~,name,~] = fileparts(file);
            [inputSignal,Fs] = audioread(file);
            
            %audio toolbox
            [analysisVector] = analyseInput(app,inputSignal,Fs);
            
            %frequency value
            temp_split = split(name,"_");
            temp_split2 = split(string(temp_split(2)),"f");
            freqString = string(temp_split2(2));
            freqInput = str2double(freqString);
            analysisVector(1,1) = freqInput;
            
            %input amplitude value
            temp_split3 = split(string(temp_split(3)),"a");
            inputAmplitudeString = string(temp_split3(2));
            inputAmplitudeInput = str2double(inputAmplitudeString);
            analysisVector(1,2) = inputAmplitudeInput;

        end
        
        function [analysisVector,Spec] = fullAnalysisExtended(app,file)
            %import fullfile path
            [~,name,~] = fileparts(file);
            [inputSignal,Fs] = audioread(file);
            
            %audio toolbox
            [analysisVector,Spec] = analyseInputExtended(app,inputSignal,Fs);
            
            %frequency value
            temp_split = split(name,"_");
            temp_split2 = split(string(temp_split(2)),"f");
            freqString = string(temp_split2(2));
            freqInput = str2double(freqString);
            analysisVector(1,1) = freqInput;
            
            %input amplitude value
            temp_split3 = split(string(temp_split(3)),"a");
            inputAmplitudeString = string(temp_split3(2));
            inputAmplitudeInput = str2double(inputAmplitudeString);
            analysisVector(1,2) = inputAmplitudeInput;
        end
        
        function [analysisVector] = analyseInput(~,inputSignal,~)
            %Insert Space for Zeros
            analysisVector(1,1:2) = 0;
            
            %RMS dB
            dBinput = rms(inputSignal);
            analysisVector(1,3) = dBinput;
            
            %THD
            THDinput = thd(inputSignal);
            analysisVector(1,4) = THDinput;
            
            %SFDR
            SFDRinput = sfdr(inputSignal);
            analysisVector(1,5) = SFDRinput;
            
            %SINAD
            SINADinput = sinad(inputSignal);
            analysisVector(1,6) = SINADinput;
            
            %SNR
            SNRinput = snr(inputSignal);
            analysisVector(1,7) = SNRinput;
            %%
        end
        
        function [folderArray] = createFolderArray(~, folderName)
            fileName = '*.wav';
            folderArray = dir(fullfile(folderName, fileName));
        end
        
        function [returnVal] = splitVector(~,vector)
            returnVal = zeros(1,length(vector));
            for i = 1:length(vector)
                returnVal(1,i) = vector(i);
            end
        end

        
        function [analysisVector,spectralAnalysis]  = analyseInputExtended(~,inputSignal,Fs)
            %Insert Space for Zeros
            analysisVector(1,1:2) = 0;
            
            %RMS dB
            dBinput = rms(inputSignal);
            analysisVector(1,3) = dBinput;
            
            %THD
            THDinput = thd(inputSignal);
            analysisVector(1,4) = THDinput;
            
            %SFDR
            SFDRinput = sfdr(inputSignal);
            analysisVector(1,5) = SFDRinput;
            
            %SINAD
            SINADinput = sinad(inputSignal);
            analysisVector(1,6) = SINADinput;
            
            %SNR
            SNRinput = snr(inputSignal);
            analysisVector(1,7) = SNRinput;
            %%
            %Spectral Whatevers
            %Spectral Centroid
            SpecCeninput = spectralCentroid(inputSignal,Fs);
            
            %Spectral Crest
            SpecCrestinput = spectralCrest(inputSignal,Fs);
            
            %Spectral Entropy
            SpecEntropyinput = spectralEntropy(inputSignal,Fs);
            
            %Spectral Flatness 
            SpecFlatinput = spectralFlatness(inputSignal,Fs);
            
            %Spectral Flux
            SpecFluxinput = spectralFlux(inputSignal,Fs);
            
            %Spectral Kurtosis
            SpecKurtinput = spectralKurtosis(inputSignal,Fs);
            
            %Spectral Skewness
            SpecSkewinput = spectralSkewness(inputSignal,Fs);
            
            %Spectral Slope
            SpecSlopeinput = spectralSlope(inputSignal,Fs);
            
            %Spectral Spread
            SpecSpreadinput = spectralSpread(inputSignal,Fs);
            
            %Sum all vectors and transpose
            spectralAnalysis = [SpecCeninput;SpecCrestinput;SpecEntropyinput;SpecFlatinput;SpecFluxinput;SpecKurtinput;SpecSkewinput;SpecSlopeinput;SpecSpreadinput];
            spectralAnalysis = spectralAnalysis.';
            
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFolderButton
        function LoadFolderButtonPushed(app, event)
            app.selpath = uigetdir;
            
            if app.CircuitAButton.Value   %where "Button1" is the name of the first radio button
                currentSelectedCircuit = "A";
                app.dirCircuitA = app.selpath;
            end
            if app.CircuitBButton.Value
                currentSelectedCircuit = "B";
                app.dirCircuitB = app.selpath;
            end
            
            if app.CircuitCButton.Value
                currentSelectedCircuit = "C";
                app.dirCircuitC = app.selpath;
            end

            if app.CircuitDButton.Value
                currentSelectedCircuit = "D";
                app.dirCircuitD = app.selpath;
            end
            
            if app.CircuitEButton.Value
                currentSelectedCircuit = "E";
                app.dirCircuitE = app.selpath;
            end
            
            if app.CircuitFButton.Value
                currentSelectedCircuit = "F";
                app.dirCircuitF = app.selpath;
            end


%             if app.switchFlag(1,1) == 1
%                 currentSelectedCircuit = "A";
%                 app.dirCircuitA = selpath;
%             end
%             
%             if app.switchFlag(1,2) == 1
%                 currentSelectedCircuit = "B";
%                 app.dirCircuitB = selpath;
%             end
%             
%             if app.switchFlag(1,3) == 1
%                 currentSelectedCircuit = "C";
%                 app.dirCircuitC = selpath;
%             end
%             
%             if app.switchFlag(1,4) == 1
%                 currentSelectedCircuit = "D";
%                 app.dirCircuitD = selpath;
%             end
%             
%             if app.switchFlag(1,5) == 1
%                 currentSelectedCircuit = "E";
%                 app.dirCircuitE = selpath;
%             end
%             
%             if app.switchFlag(1,6) == 1
%                 currentSelectedCircuit = "F";
%                 app.dirCircuitF = selpath;
%             end
            %function to get and print the path
            
            sentence1 = "The current path for Circuit %s chosen is: %s";
            sentence = sentence1;
            currentPrint = sprintf(sentence,currentSelectedCircuit,app.selpath);
            app.OutputTextArea.Value = currentPrint;
            
            pause(2);
            
            %x = 0:1:100;
            %y = 2*x;
            %plot(app.UIAxes,x,y)
            if isempty(app.dirCircuitA)
                app.LampA.Color = [1.0,0.0,0.0];
            else
                app.LampA.Color = [0.0,1.0,0.0];
            end
            
            if isempty(app.dirCircuitB)
                app.LampB.Color = [1.0,0.0,0.0];
            else
                app.LampB.Color = [0.0,1.0,0.0];
            end

            if isempty(app.dirCircuitC)
                app.LampC.Color = [1.0,0.0,0.0];
            else
                app.LampC.Color = [0.0,1.0,0.0];
            end

            if isempty(app.dirCircuitD)
                app.LampD.Color = [1.0,0.0,0.0];
            else
                app.LampD.Color = [0.0,1.0,0.0];
            end

            if isempty(app.dirCircuitE)
                app.LampE.Color = [1.0,0.0,0.0];
            else
                app.LampE.Color = [0.0,1.0,0.0];
            end
            
            if isempty(app.dirCircuitF)
                app.LampF.Color = [1.0,0.0,0.0];
            else
                app.LampF.Color = [0.0,1.0,0.0];
            end
            
        end

        % Button pushed function: ExtractFeaturesButton
        function ExtractFeaturesButtonPushed(app, event)
            sentence2 = "Performing Feature Analysis for inputs of all selected Circuits, please wait...";
            currentPrint = sprintf(sentence2);
            app.OutputTextArea.Value = currentPrint;
            drawnow;
            
            if isempty(app.datasetA) && app.LampA.Color(2) == 1.0
                %currentSelectedCircuit = "A";
                folderArrayCircuitA = createFolderArray(app,app.dirCircuitA);
                for fileNum = 1:length(folderArrayCircuitA)
                    
                    fileNameA = sprintf('%s%s', app.dirCircuitA,'/',folderArrayCircuitA(fileNum).name);
                    [analysisVectorA] = fullAnalysis(app, fileNameA);
                    app.datasetA(fileNum,:) = analysisVectorA;
                    
                    %show something to show its working.
                    sentence3 = 'Performing Feature Analysis for inputs of selected Circuits, please wait... (Circuit A) File Number %s of %s. \n Directory: %s';
                    sentence3 = sprintf(sentence3, string(fileNum), string(length(folderArrayCircuitA)), fileNameA);
                    app.OutputTextArea.Value = sentence3;
                    drawnow;
                end
            end
            
            if isempty(app.datasetB) && app.LampB.Color(2) == 1.0
                %currentSelectedCircuit = "B";
                folderArrayCircuitB = createFolderArray(app,app.dirCircuitB);
                for fileNum = 1:length(folderArrayCircuitB)
                    
                    fileNameB = sprintf('%s%s', app.dirCircuitB,'/',folderArrayCircuitB(fileNum).name);
                    [analysisVectorB] = fullAnalysis(app, fileNameB);
                    app.datasetB(fileNum,:) = analysisVectorB;
                    
                    %show something to show its working.
                    sentence3 = 'Performing Feature Analysis for inputs of selected Circuits, please wait... (Circuit B) File Number %s of %s. \n Directory: %s';
                    sentence3 = sprintf(sentence3, string(fileNum), string(length(folderArrayCircuitB)), fileNameB);
                    app.OutputTextArea.Value = sentence3;
                    drawnow;
                end
            end
            
            if isempty(app.datasetC) && app.LampC.Color(2) == 1.0
                %currentSelectedCircuit = "C";
                folderArrayCircuitC = createFolderArray(app,app.dirCircuitC);
                for fileNum = 1:length(folderArrayCircuitC)
                    
                    fileNameC = sprintf('%s%s', app.dirCircuitC,'/',folderArrayCircuitC(fileNum).name);
                    [analysisVectorC] = fullAnalysis(app, fileNameC);
                    app.datasetC(fileNum,:) = analysisVectorC;
                    
                    %show something to show its working.
                    sentence3 = 'Performing Feature Analysis for inputs of selected Circuits, please wait... (Circuit C) File Number %s of %s. \n Directory: %s';
                    sentence3 = sprintf(sentence3, string(fileNum), string(length(folderArrayCircuitC)), fileNameC);
                    app.OutputTextArea.Value = sentence3;
                    drawnow;
                end
            end
            
            if isempty(app.datasetD) && app.LampD.Color(2) == 1.0
                %currentSelectedCircuit = "D";
                folderArrayCircuitD = createFolderArray(app,app.dirCircuitD);
                for fileNum = 1:length(folderArrayCircuitD)
                    
                    fileNameD = sprintf('%s%s', app.dirCircuitD,'/',folderArrayCircuitD(fileNum).name);
                    [analysisVectorD] = fullAnalysis(app, fileNameD);
                    app.datasetD(fileNum,:) = analysisVectorD;
                    
                    %show something to show its working.
                    sentence3 = 'Performing Feature Analysis for inputs of selected Circuits, please wait... (Circuit D) File Number %s of %s. \n Directory: %s';
                    sentence3 = sprintf(sentence3, string(fileNum), string(length(folderArrayCircuitD)), fileNameD);
                    app.OutputTextArea.Value = sentence3;
                    drawnow;
                end
            end
            
            if isempty(app.datasetE) && app.LampE.Color(2) == 1.0
                %currentSelectedCircuit = "E";
                folderArrayCircuitE = createFolderArray(app,app.dirCircuitE);
                for fileNum = 1:length(folderArrayCircuitE)
                    
                    fileNameE = sprintf('%s%s', app.dirCircuitE,'/',folderArrayCircuitE(fileNum).name);
                    [analysisVectorE] = fullAnalysis(app, fileNameE);
                    app.datasetE(fileNum,:) = analysisVectorE;
                    
                    %show something to show its working.
                    sentence3 = 'Performing Feature Analysis for inputs of selected Circuits, please wait... (Circuit E) File Number %s of %s. \n Directory: %s';
                    sentence3 = sprintf(sentence3, string(fileNum), string(length(folderArrayCircuitE)), fileNameE);
                    app.OutputTextArea.Value = sentence3;
                    drawnow;
                end
            end
            
            if isempty(app.datasetF) && app.LampF.Color(2) == 1.0
                %currentSelectedCircuit = "F";
                folderArrayCircuitF = createFolderArray(app,app.dirCircuitF);
                for fileNum = 1:length(folderArrayCircuitF)
                    
                    fileNameF = sprintf('%s%s', app.dirCircuitF,'/',folderArrayCircuitF(fileNum).name);
                    [analysisVectorF] = fullAnalysis(app, fileNameF);
                    app.datasetF(fileNum,:) = analysisVectorF;
                    
                    %show something to show its working.
                    sentence3 = 'Performing Feature Analysis for inputs of selected Circuits, please wait... (Circuit F) File Number %s of %s. \n Directory: %s';
                    sentence3 = sprintf(sentence3, string(fileNum), string(length(folderArrayCircuitF)), fileNameF);
                    app.OutputTextArea.Value = sentence3;
                    drawnow;
                end
            end
            
            sentence2 = "Finished performing Feature Analysis for inputs of all selected Circuits";
            currentPrint = sprintf(sentence2);
            app.OutputTextArea.Value = currentPrint;
            
            
        end

        % Button pushed function: ExtractExtendedFeaturesButton
        function ExtractExtendedFeaturesButtonPushed(app, event)
            sentence2 = "Performing Extended Feature Analysis for inputs of all selected Circuits, please wait...";
            currentPrint = sprintf(sentence2);
            app.OutputTextArea.Value = currentPrint;
            drawnow;
            
            if isempty(app.datasetAenhanced) && app.LampA.Color(2) == 1.0
                %currentSelectedCircuit = "A";
                folderArrayCircuitA = createFolderArray(app,app.dirCircuitA);
                for fileNum = 1:length(folderArrayCircuitA)
                    
                    fileNameA = sprintf('%s%s', app.dirCircuitA,'/',folderArrayCircuitA(fileNum).name);
                    [analysisVectorA, spectralInfoA] = fullAnalysisExtended(app, fileNameA);
                    app.datasetAenhanced(fileNum,:) = [analysisVectorA,spectralInfoA];
                    
                    %show something to show its working.
                    sentence3 = 'Performing Extended Feature Analysis for inputs of selected Circuits, please wait... (Circuit A) File Number %s of %s. \n Directory: %s';
                    sentence3 = sprintf(sentence3, string(fileNum), string(length(folderArrayCircuitA)), fileNameA);
                    app.OutputTextArea.Value = sentence3;
                    drawnow;
                end
            end
            
            if isempty(app.datasetBenhanced) && app.LampB.Color(2) == 1.0
                %currentSelectedCircuit = "B";
                folderArrayCircuitB = createFolderArray(app,app.dirCircuitB);
                for fileNum = 1:length(folderArrayCircuitB)
                    
                    fileNameB = sprintf('%s%s', app.dirCircuitB,'/',folderArrayCircuitB(fileNum).name);
                    [analysisVectorB, spectralInfoB] = fullAnalysisExtended(app, fileNameB);
                    app.datasetBenhanced(fileNum,:) = [analysisVectorB,spectralInfoB];
                    
                    %show something to show its working.
                    sentence3 = 'Performing Extended Feature Analysis for inputs of selected Circuits, please wait... (Circuit B) File Number %s of %s. \n Directory: %s';
                    sentence3 = sprintf(sentence3, string(fileNum), string(length(folderArrayCircuitB)), fileNameB);
                    app.OutputTextArea.Value = sentence3;
                    drawnow;
                end
            end
            
            if isempty(app.datasetCenhanced) && app.LampC.Color(2) == 1.0
                %currentSelectedCircuit = "C";
                folderArrayCircuitC = createFolderArray(app,app.dirCircuitC);
                for fileNum = 1:length(folderArrayCircuitC)
                    
                    fileNameC = sprintf('%s%s', app.dirCircuitC,'/',folderArrayCircuitC(fileNum).name);
                    [analysisVectorC, spectralInfoC] = fullAnalysisExtended(app, fileNameC);
                    app.datasetCenhanced(fileNum,:) = [analysisVectorC,spectralInfoC];
                    
                    %show something to show its working.
                    sentence3 = 'Performing Extended Feature Analysis for inputs of selected Circuits, please wait... (Circuit C) File Number %s of %s. \n Directory: %s';
                    sentence3 = sprintf(sentence3, string(fileNum), string(length(folderArrayCircuitC)), fileNameC);
                    app.OutputTextArea.Value = sentence3;
                    drawnow;
                end
            end
            
            if isempty(app.datasetDenhanced) && app.LampD.Color(2) == 1.0
                %currentSelectedCircuit = "D";
                folderArrayCircuitD = createFolderArray(app,app.dirCircuitD);
                for fileNum = 1:length(folderArrayCircuitD)
                    
                    fileNameD = sprintf('%s%s', app.dirCircuitD,'/',folderArrayCircuitD(fileNum).name);
                    [analysisVectorD, spectralInfoD] = fullAnalysisExtended(app, fileNameD);
                    app.datasetDenhanced(fileNum,:) = [analysisVectorD,spectralInfoD];
                    
                    %show something to show its working.
                    sentence3 = 'Performing Extended Feature Analysis for inputs of selected Circuits, please wait... (Circuit D) File Number %s of %s. \n Directory: %s';
                    sentence3 = sprintf(sentence3, string(fileNum), string(length(folderArrayCircuitD)), fileNameD);
                    app.OutputTextArea.Value = sentence3;
                    drawnow;
                end
            end
            
            if isempty(app.datasetEenhanced) && app.LampE.Color(2) == 1.0
                %currentSelectedCircuit = "E";
                folderArrayCircuitE = createFolderArray(app,app.dirCircuitE);
                for fileNum = 1:length(folderArrayCircuitE)
                    
                    fileNameE = sprintf('%s%s', app.dirCircuitE,'/',folderArrayCircuitE(fileNum).name);
                    [analysisVectorE, spectralInfoE] = fullAnalysisExtended(app, fileNameE);
                    app.datasetEenhanced(fileNum,:) = [analysisVectorE,spectralInfoE];
                    
                    %show something to show its working.
                    sentence3 = 'Performing Extended Feature Analysis for inputs of selected Circuits, please wait... (Circuit E) File Number %s of %s. \n Directory: %s';
                    sentence3 = sprintf(sentence3, string(fileNum), string(length(folderArrayCircuitE)), fileNameE);
                    app.OutputTextArea.Value = sentence3;
                    drawnow;
                end
            end
            
            if isempty(app.datasetFenhanced) && app.LampF.Color(2) == 1.0
                %currentSelectedCircuit = "F";
                folderArrayCircuitF = createFolderArray(app,app.dirCircuitF);
                for fileNum = 1:length(folderArrayCircuitF)
                    
                    fileNameF = sprintf('%s%s', app.dirCircuitF,'/',folderArrayCircuitF(fileNum).name);
                    [analysisVectorF, spectralInfoF] = fullAnalysisExtended(app, fileNameF);
                    app.datasetFenhanced(fileNum,:) = [analysisVectorF,spectralInfoF];
                    
                    %show something to show its working.
                    sentence3 = 'Performing Extended Feature Analysis for inputs of selected Circuits, please wait... (Circuit F) File Number %s of %s. \n Directory: %s';
                    sentence3 = sprintf(sentence3, string(fileNum), string(length(folderArrayCircuitF)), fileNameF);
                    app.OutputTextArea.Value = sentence3;
                    drawnow;
                end
            end
            sentence2 = "Finished performing Extended Feature Analysis for inputs of all selected Circuits";
            currentPrint = sprintf(sentence2);
            app.OutputTextArea.Value = currentPrint;
        end

        % Button pushed function: TrainModelButton
        function TrainModel(app, event)


            if isempty(app.datasetA)
                sentence = "(ALERT)Dataset for Circuit A is empty:\n";
                printSentence = sprintf(sentence);
                app.OutputTextArea.Value = printSentence;
                drawnow;
                pause(1)
            else
                app.datasetA(:,end+1) = 1;
                app.trainingData = app.datasetA;
%                 for i = 1:length(app.trainingData)
%                 sentence = "%s";
%                 printData = string(app.trainingData(i,:));
%                 printSentence = sprintf(sentence, printData);
%                 app.OutputTextArea.Value = printSentence;
%                 drawnow;
%                 pause(1)
%                 end
                
            end
            
            
            if isempty(app.datasetB)
                sentence2 = "(ALERT)Dataset for Circuit B is empty:\n";
                sentence = sentence2;
                sentence = join(sentence);
                printSentence = sprintf(sentence);
                app.OutputTextArea.Value = printSentence;
                drawnow;
                pause(1)
            else
                app.datasetB(:,end+1) = 2;
                app.trainingData = [app.trainingData;app.datasetB];
            end
            
            
            if isempty(app.datasetC)
                sentence3 = "(ALERT)Dataset for Circuit C is empty:\n";
                sentence = sentence3;
                sentence = join(sentence);
                printSentence = sprintf(sentence);
                app.OutputTextArea.Value = printSentence;
                drawnow;
                pause(1)
            else
                app.datasetC(:,end+1) = 3;
                app.trainingData = [app.trainingData;app.datasetC];
            end
            
            
            if isempty(app.datasetD)
                sentence4 = "(ALERT)Dataset for Circuit D is empty:\n";
                sentence = sentence4;
                sentence = join(sentence);
                printSentence = sprintf(sentence);
                app.OutputTextArea.Value = printSentence;
                drawnow;
                pause(1)
            else
                app.datasetD(:,end+1) = 4;
                app.trainingData = [app.trainingData;app.datasetD];
            end
            
            
            if isempty(app.datasetE)
                sentence5 = "(ALERT)Dataset for Circuit E is empty:\n";
                sentence = sentence5;
                sentence = join(sentence);
                printSentence = sprintf(sentence);
                app.OutputTextArea.Value = printSentence;
                drawnow;
                pause(1)
            else
                app.datasetE(:,end+1) = 5;
                app.trainingData = [app.trainingData;app.datasetE];
            end
            
            
            if isempty(app.datasetF)
                sentence6 = "(ALERT)Dataset for Circuit F is empty:\n";
                sentence = sentence6;
                sentence = join(sentence);
                printSentence = sprintf(sentence);
                app.OutputTextArea.Value = printSentence;
                drawnow;
                pause(1)
            else
                app.datasetF(:,end+1) = 6;
                app.trainingData = [app.trainingData;app.datasetF];
            end
            
            sentence2 = "Training Model, please wait...";
            currentPrint = sprintf(sentence2);
            app.OutputTextArea.Value = currentPrint;
            drawnow;


            rng(1);
            t = templateSVM('Standardize',true,'KernelFunction','gaussian');
            app.X = app.trainingData(:,1:6);
%             writematrix(app.X,"X.csv")
            app.Y = app.trainingData(:,end);
%             writematrix(app.Y,"Y.csv")
%             app.X = readmatrix("X.csv");
%             app.Y = readmatrix("Y.csv");
            Mdl = fitcecoc(app.X,app.Y,'Learners',t);
            CVMdl = crossval(Mdl,'Holdout',0.3);        
%             ConfMat = confusionchart(app.trainingData(:,end),oofLabel);
%             genError = kfoldLoss(CVMdl);
            [app.oofLabel,escore] = kfoldPredict(CVMdl);
            resultsArray = app.Y==app.oofLabel;
            numberOfZeros = sum(resultsArray(:)==0);
            genError = numberOfZeros/length(resultsArray);
            simIndex = genError;
            simIndex = string(simIndex);
            outputSentence = "%s";
            printSentence = sprintf(outputSentence, simIndex);
            app.SimilarityIndexTextArea.Value = printSentence;
            
            % kfoldPredict
            
            highestEscore = max(abs(escore));
            escoreSentence = '%s, ';
            printSentenceE = sprintf(escoreSentence, string(highestEscore));
            app.HighesteScoreTextArea.Value = printSentenceE(1:end-2);


            sentence2 = "Training Model complete. Please see results below. \n Plot confusion matrix for detailed view of True Labels vs Predicted Labels";
            currentPrint = sprintf(sentence2);
            app.OutputTextArea.Value = currentPrint;
            drawnow;
        end

        % Button pushed function: 
        % TrainModelwithExtendedFeaturesButton_2
        function TrainModelwithExtendedFeaturesButton_2Pushed(app, event)
            
            sentence = "";
            if isempty(app.datasetAenhanced)
                sentence = "(ALERT)Dataset for Circuit A is empty:\n";
                printSentence = sprintf(sentence);
                app.OutputTextArea.Value = printSentence;
                drawnow;
            else
                app.datasetAenhanced(:,end+1) = 1;
                app.trainingDataEnhanced = app.datasetAenhanced;
            end
            pause(1)
            if isempty(app.datasetBenhanced)
                sentence2 = "(ALERT)Dataset for Circuit B is empty:\n";
                sentence = [sentence,sentence2];
                sentence = join(sentence);
                printSentence = sprintf(sentence);
                app.OutputTextArea.Value = printSentence;
                drawnow;
            else
                app.datasetBenhanced(:,end+1) = 2;
                app.trainingDataEnhanced = [app.trainingDataEnhanced;app.datasetBenhanced];
            end
            pause(1)
            if isempty(app.datasetCenhanced)
                sentence3 = "(ALERT)Dataset for Circuit C is empty:\n";
                sentence = [sentence,sentence3];
                sentence = join(sentence);
                printSentence = sprintf(sentence);
                app.OutputTextArea.Value = printSentence;
                drawnow;
            else
                app.datasetCenhanced(:,end+1) = 3;
                app.trainingDataEnhanced = [app.trainingDataEnhanced;app.datasetCenhanced];
            end
            pause(1)
            if isempty(app.datasetDenhanced)
                sentence4 = "(ALERT)Dataset for Circuit D is empty:\n";
                sentence = [sentence,sentence4];
                sentence = join(sentence);
                printSentence = sprintf(sentence);
                app.OutputTextArea.Value = printSentence;
                drawnow;
            else
                app.datasetDenhanced(:,end+1) = 4;
                app.trainingDataEnhanced = [app.trainingDataEnhanced;app.datasetDenhanced];
            end
            pause(1)
            if isempty(app.datasetEenhanced)
                sentence5 = "(ALERT)Dataset for Circuit E is empty:\n";
                sentence = [sentence,sentence5];
                sentence = join(sentence);
                printSentence = sprintf(sentence);
                app.OutputTextArea.Value = printSentence;
                drawnow;
            else
                app.datasetEenhanced(:,end+1) = 5;
                app.trainingDataEnhanced = [app.trainingDataEnhanced;app.datasetEenhanced];
            end
            pause(1)
            if isempty(app.datasetFenhanced)
                sentence6 = "(ALERT)Dataset for Circuit F is empty:\n";
                sentence = [sentence,sentence6];
                sentence = join(sentence);
                printSentence = sprintf(sentence);
                app.OutputTextArea.Value = printSentence;
                drawnow;
            else
                app.datasetFenhanced(:,end+1) = 6;
                app.trainingDataEnhanced = [app.trainingDataEnhanced;app.datasetFenhanced];
            end
            pause(1)
            sentence2 = "Training Model, please wait...";
            currentPrint = sprintf(sentence2);
            app.OutputTextArea.Value = currentPrint;
            drawnow;
            
            
            rng(1);
            t = templateSVM('Standardize',true,'KernelFunction','gaussian');
            app.X_Enhanced = app.trainingDataEnhanced(:,1:end-1);
%             writematrix(app.X_Enhanced,"X.csv")
            app.Y_Enhanced = app.trainingDataEnhanced(:,end);
%             writematrix(app.Y_Enhanced,"Y.csv")
            Mdl = fitcecoc(app.X_Enhanced,app.Y_Enhanced,'Learners',t);
            CVMdl = crossval(Mdl, "Holdout", 0.3);
            [app.oofLabel,escore] = kfoldPredict(CVMdl);
            resultsArray = app.Y_Enhanced==app.oofLabel;
            numberOfZeros = sum(resultsArray(:)==0);
            genError = numberOfZeros/length(resultsArray);
            simIndex = genError;
            simIndex = string(simIndex);
            outputSentence = "%s";
            printSentence = sprintf(outputSentence, simIndex);
            app.SimilarityIndexTextArea.Value = printSentence;
            
            % kfoldPredict
            
            highestEscore = max(abs(escore));
            escoreSentence = '%s, ';
            printSentenceE = sprintf(escoreSentence, string(highestEscore));
            app.HighesteScoreTextArea.Value = printSentenceE(1:end-2);
            
            sentence2 = "Training Model complete. Please see results below. \n Plot confusion matrix for detailed view of True Labels vs Predicted Labels";
            currentPrint = sprintf(sentence2);
            app.OutputTextArea.Value = currentPrint;
            drawnow;
        
% %             ConfMat = confusionchart(app.trainingData(:,end),oofLabel);
%             genError = kfoldLoss(CVMdl);
%             simIndex = genError;
%             simIndex = string(simIndex);
%             outputSentence = "%s";
%             printSentence = sprintf(outputSentence, simIndex);
%             app.SimilarityIndexTextArea.Value = printSentence;
%             
%             % kfoldPredict
%             [~,escore] = kfoldPredict(CVMdl);
%             highestEscore = max(escore);
%             escoreSentence = "%s";
%             printSentenceE = sprintf(escoreSentence, string(highestEscore));
%             app.HighesteScoreTextArea.Value = printSentenceE;
%             app.oofLabel = kfoldPredict(CVMdl);
%             
%             sentence2 = "Training Model complete. Please see results below. \n Plot confusion matrix for detailed view of True Labels vs Predicted Labels";
%             currentPrint = sprintf(sentence2);
%             app.OutputTextArea.Value = currentPrint;
%             drawnow;
        end

        % Button pushed function: PlotConfusionMatrixButton
        function PlotConfusionMatrixButtonPushed(app, event)
            hold(app.UIAxes,"off");
            app.HoldSwitch.Value = "Off";
            confusionchart(app.Y,app.oofLabel,'RowSummary','total-normalized');
        end

        % Button pushed function: 
        % PlotConfusionMatrixforModelExtButton
        function PlotConfusionMatrixforModelExtButtonPushed(app, event)
            hold(app.UIAxes,"off");
            app.HoldSwitch.Value = "Off";
            confusionchart(app.Y_Enhanced,app.oofLabel,'RowSummary','total-normalized');
        end

        % Button pushed function: PlotTHDAButton
        function PlotTHDAButtonPushed(app, event)
            app.THDA = plot3(app.UIAxes,app.datasetA(:,1),app.datasetA(:,2),app.datasetA(:,4),'o','Color','red');
            drawnow;
        end

        % Button pushed function: PlotTHDBButton
        function PlotTHDBButtonPushed(app, event)
            app.THDB = plot3(app.UIAxes,app.datasetB(:,1),app.datasetB(:,2),app.datasetB(:,4),'o','Color','green');
            drawnow;
        end

        % Button pushed function: PlotTHDCButton
        function PlotTHDCButtonPushed(app, event)
            app.THDC = plot3(app.UIAxes,app.datasetC(:,1),app.datasetC(:,2),app.datasetC(:,4),'o','Color','blue');
            drawnow;
        end

        % Button pushed function: PlotTHDDButton
        function PlotTHDDButtonPushed(app, event)
            app.THDD = plot3(app.UIAxes,app.datasetD(:,1),app.datasetD(:,2),app.datasetD(:,4),'o','Color','cyan');
            drawnow;
        end

        % Button pushed function: PlotTHDEButton
        function PlotTHDEButtonPushed(app, event)
            app.THDE = plot3(app.UIAxes,app.datasetE(:,1),app.datasetE(:,2),app.datasetE(:,4),'o','Color','magenta');
            drawnow;
        end

        % Button pushed function: PlotTHDFButton
        function PlotTHDFButtonPushed(app, event)
            app.THDF = plot3(app.UIAxes,app.datasetF(:,1),app.datasetF(:,2),app.datasetF(:,4),'o','Color','yellow');
            drawnow;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
              app.datasetA = [];
              app.datasetB = [];
              app.datasetC = [];
              app.datasetD = [];
              app.datasetE = [];
              app.datasetF = [];
             
              app.datasetAenhanced = [];
              app.datasetBenhanced = [];
              app.datasetCenhanced = [];
              app.datasetDenhanced = [];
              app.datasetEenhanced = [];
              app.datasetFenhanced = []; % Datasets for storage
              
              app.trainingData = [];
              app.trainingDataEnhanced = [];
              
              app.X = [];
              app.X_Enhanced = [];
              app.Y = [];
              app.Y_Enhanced = [];
              
              app.oofLabel = [];
              
              app.LampA.Color = [1.0,0.0,0.0];
              app.LampB.Color = [1.0,0.0,0.0];
              app.LampC.Color = [1.0,0.0,0.0];
              app.LampD.Color = [1.0,0.0,0.0];
              app.LampE.Color = [1.0,0.0,0.0];
              app.LampF.Color = [1.0,0.0,0.0];
              
        end

        % Callback function
        function HoldSwitchValueChanged(app, event)
            value = app.HoldSwitch.Value;
            if value == 1
                hold(app.UIAxes,'on')
                sentence2 = "%s";
                currentPrint = sprintf(sentence2,value);
                app.OutputTextArea.Value = currentPrint;
                drawnow;
            else
                hold(app.UIAxes,'off')
            end
        end

        % Value changed function: HoldSwitch
        function HoldSwitchValueChanged2(app, event)
            value = app.HoldSwitch.Value;
            
            hold(app.UIAxes,value)

            sentence2 = "%s";
                currentPrint = sprintf(sentence2,value);
                app.OutputTextArea.Value = currentPrint;
                drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadFolderButton = uibutton(app.UIFigure, 'push');
            app.LoadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderButtonPushed, true);
            app.LoadFolderButton.Text = 'Load Folder';
            app.LoadFolderforButtonGroup = uibuttongroup(app.UIFigure);
            app.LoadFolderforButtonGroup.Title = 'Load Folder for:';
            app.CircuitAButton = uiradiobutton(app.LoadFolderforButtonGroup);
            app.CircuitAButton.Text = 'Circuit A';
            app.CircuitAButton.Value = true;
            app.CircuitBButton = uiradiobutton(app.LoadFolderforButtonGroup);
            app.CircuitBButton.Text = 'Circuit B';
            app.CircuitCButton = uiradiobutton(app.LoadFolderforButtonGroup);
            app.CircuitCButton.Text = 'Circuit C';
            app.CircuitDButton = uiradiobutton(app.LoadFolderforButtonGroup);
            app.CircuitDButton.Text = 'Circuit D';
            app.CircuitEButton = uiradiobutton(app.LoadFolderforButtonGroup);
            app.CircuitEButton.Text = 'Circuit E';
            app.CircuitFButton = uiradiobutton(app.LoadFolderforButtonGroup);
            app.CircuitFButton.Text = 'Circuit F';
            app.LampA = uilamp(app.LoadFolderforButtonGroup);
            app.LampB = uilamp(app.LoadFolderforButtonGroup);
            app.LampC = uilamp(app.LoadFolderforButtonGroup);
            app.LampD = uilamp(app.LoadFolderforButtonGroup);
            app.LampE = uilamp(app.LoadFolderforButtonGroup);
            app.LampF = uilamp(app.LoadFolderforButtonGroup);
            app.OutputTextAreaLabel = uilabel(app.UIFigure);
            app.OutputTextAreaLabel.Text = 'Output';
            app.OutputTextArea = uitextarea(app.UIFigure);
            app.TrainModelButton = uibutton(app.UIFigure, 'push');
            app.TrainModelButton.ButtonPushedFcn = createCallbackFcn(app, @TrainModel, true);
            app.TrainModelButton.Text = 'Train Model';
            app.TrainModelLabel = uilabel(app.UIFigure);
            app.TrainModelLabel.Text = {'Extract features before training respective models. Upon successfully '; 'training the model, the results will be shown in the fields below.'};
            app.SimilarityIndexTextAreaLabel = uilabel(app.UIFigure);
            app.SimilarityIndexTextAreaLabel.Text = {'Similarity'; 'Index'};
            app.SimilarityIndexTextArea = uitextarea(app.UIFigure);
            app.HighesteScoreTextAreaLabel = uilabel(app.UIFigure);
            app.HighesteScoreTextAreaLabel.Text = {'Highest'; 'eScore'};
            app.HighesteScoreTextArea = uitextarea(app.UIFigure);
            app.TrainModelwithExtendedFeaturesButton_2 = uibutton(app.UIFigure, 'push');
            app.TrainModelwithExtendedFeaturesButton_2.ButtonPushedFcn = createCallbackFcn(app, @TrainModelwithExtendedFeaturesButton_2Pushed, true);
            app.TrainModelwithExtendedFeaturesButton_2.Text = 'Train Model with Extended Features';
            app.PlotConfusionMatrixButton = uibutton(app.UIFigure, 'push');
            app.PlotConfusionMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @PlotConfusionMatrixButtonPushed, true);
            app.PlotConfusionMatrixButton.Text = 'Plot Confusion Matrix';
            app.PlotTHDAButton = uibutton(app.UIFigure, 'push');
            app.PlotTHDAButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTHDAButtonPushed, true);
            app.PlotTHDAButton.Text = 'Plot THD (A)';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ExtractFeaturesButton = uibutton(app.UIFigure, 'push');
            app.ExtractFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractFeaturesButtonPushed, true);
            app.ExtractFeaturesButton.Text = 'Extract Features';
            app.ExtractExtendedFeaturesButton = uibutton(app.UIFigure, 'push');
            app.ExtractExtendedFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractExtendedFeaturesButtonPushed, true);
            app.ExtractExtendedFeaturesButton.Text = 'Extract Extended Features';
            app.TrainModelLabel_2 = uilabel(app.UIFigure);
            app.TrainModelLabel_2.Text = '_________________________________________________________________________________________________________';
            app.PlotTHDBButton = uibutton(app.UIFigure, 'push');
            app.PlotTHDBButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTHDBButtonPushed, true);
            app.PlotTHDBButton.Text = 'Plot THD (B)';
            app.PlotTHDCButton = uibutton(app.UIFigure, 'push');
            app.PlotTHDCButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTHDCButtonPushed, true);
            app.PlotTHDCButton.Text = 'Plot THD (C)';
            app.PlotTHDDButton = uibutton(app.UIFigure, 'push');
            app.PlotTHDDButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTHDDButtonPushed, true);
            app.PlotTHDDButton.Text = 'Plot THD (D)';
            app.PlotTHDEButton = uibutton(app.UIFigure, 'push');
            app.PlotTHDEButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTHDEButtonPushed, true);
            app.PlotTHDEButton.Text = 'Plot THD (E)';
            app.PlotTHDFButton = uibutton(app.UIFigure, 'push');
            app.PlotTHDFButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTHDFButtonPushed, true);
            app.PlotTHDFButton.Text = 'Plot THD (F)';
            app.HoldSwitchLabel = uilabel(app.UIFigure);
            app.HoldSwitchLabel.Text = 'Hold';
            app.HoldSwitch = uiswitch(app.UIFigure, 'slider');
            app.HoldSwitch.ValueChangedFcn = createCallbackFcn(app, @HoldSwitchValueChanged2, true);
            app.PlotConfusionMatrixforModelExtButton = uibutton(app.UIFigure, 'push');
            app.PlotConfusionMatrixforModelExtButton.ButtonPushedFcn = createCallbackFcn(app, @PlotConfusionMatrixforModelExtButtonPushed, true);
            app.PlotConfusionMatrixforModelExtButton.Text = 'Plot Confusion Matrix for Model(Ext)';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'THD Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lineampprogram

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end